classdef car_move_A01383048 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        equationEditFieldLabel    matlab.ui.control.Label
        equationEditField         matlab.ui.control.EditField
        runButton                 matlab.ui.control.Button
        startEditFieldLabel       matlab.ui.control.Label
        startEditField            matlab.ui.control.NumericEditField
        endEditFieldLabel         matlab.ui.control.Label
        endEditField              matlab.ui.control.NumericEditField
        stepsEditFieldLabel       matlab.ui.control.Label
        stepsEditField            matlab.ui.control.NumericEditField
        carposxEditFieldLabel     matlab.ui.control.Label
        carposxEditField          matlab.ui.control.NumericEditField
        carposyEditFieldLabel     matlab.ui.control.Label
        carposyEditField          matlab.ui.control.NumericEditField
        xstartaxisEditFieldLabel  matlab.ui.control.Label
        xstartaxisEditField       matlab.ui.control.NumericEditField
        xendaxisEditFieldLabel    matlab.ui.control.Label
        xendaxisEditField         matlab.ui.control.NumericEditField
        ystartaxisEditFieldLabel  matlab.ui.control.Label
        ystartaxisEditField       matlab.ui.control.NumericEditField
        yendaxisEditFieldLabel    matlab.ui.control.Label
        yendaxisEditField         matlab.ui.control.NumericEditField
        curveareaEditFieldLabel   matlab.ui.control.Label
        curveareaEditField        matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
    end

     
    methods (Access = private)
        
        function results = func_test(app)
            disp("in the function")
        end
        
        function area = areatrapecios(app, ecuation, left_point, ...
                                               right_point, height)
            left_base= app.altura(ecuation, left_point);
            right_base = app.altura(ecuation, right_point);
            area = (height*(left_base + right_base))/2;
        end

        function altura = altura(app, eqn, punto)
            func = matlabFunction(str2sym(eqn));
            altura = func(punto);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            % get all from UI
%              equation = app.equationEditField.Value;
%              start = app.startEditField.Value;
%              finish = app.endEditField.Value;
%              steps = app.stepsEditField.Value;
%              x_start_axis = app.xstartaxisEditField.Value;
%              x_end_axis = app.xendaxisEditField.Value;
%              y_start_axis = app.ystartaxisEditField.Value;
%              y_end_axis = app.yendaxisEditField.Value;
%             hard coded values
             app.func_test()
             equation = "x^2";
             start = 0;
             finish = 10;
             steps = .1;
             x_start_axis = -5;
             x_end_axis = 50;
             y_start_axis = -20;
             y_end_axis = 150;
            %convert the equation string function into an anonymus function
            car_move_equation = matlabFunction(str2sym(equation));
            % with hard coded values @(x) x^2
            x_points = [start : steps : finish];
            y_points = [];
            
            
            axis(app.UIAxes, [x_start_axis..., 
                              x_end_axis,...
                              y_start_axis..., 
                              y_end_axis])
            % first iterate for getting the correct plot with the line
            % that the car will pass
            for index = 1 : length(x_points)
                y_points(index) = car_move_equation(x_points(index));
            end
            
            hold(app.UIAxes);
            % plot for the trajectory
            plot(app.UIAxes,x_points, y_points);
            % plot for the car
            plot_to_draw_car = plot(app.UIAxes,... 
                         x_points(1),...
                         y_points(1),...
                         'ro', 'MarkerFaceColor','red');
            area_acumulada = 0;
            nextPoint = -1;
            %move the car thru the line
             for index = 1 : length(x_points)
                app.curveareaEditField.Value = area_acumulada;
                if(index+1<=length(x_points))
                    nextPoint = x_points(index+1);
                else
                    nextPoint = -1;
                end
                
                if(nextPoint >-1)
                    area_acumulada = area_acumulada + ...
                    app.areatrapecios(equation, ...
                                        x_points(index), nextPoint, steps);
                end
                % app.curveareaEditField.Value = area_acum;
                y_pos = car_move_equation(x_points(index));
                plot_to_draw_car.XData = x_points(index);
                plot_to_draw_car.YData = y_pos;
                drawnow
                app.carposxEditField.Value = x_points(index);
                app.carposyEditField.Value = y_pos;
                
                %time to sleep thread
                pause(0.20);
             end
        end

        % Value changed function: curveareaEditField
        function curveareaEditFieldValueChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.equationEditFieldLabel = uilabel(app.UIFigure);
            app.equationEditFieldLabel.Text = 'equation';
            app.equationEditField = uieditfield(app.UIFigure, 'text');
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.startEditFieldLabel = uilabel(app.UIFigure);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.UIFigure, 'numeric');
            app.endEditFieldLabel = uilabel(app.UIFigure);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepsEditFieldLabel = uilabel(app.UIFigure);
            app.stepsEditFieldLabel.Text = 'steps';
            app.stepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.carposxEditFieldLabel = uilabel(app.UIFigure);
            app.carposxEditFieldLabel.Text = 'car pos x';
            app.carposxEditField = uieditfield(app.UIFigure, 'numeric');
            app.carposyEditFieldLabel = uilabel(app.UIFigure);
            app.carposyEditFieldLabel.Text = 'car pos y';
            app.carposyEditField = uieditfield(app.UIFigure, 'numeric');
            app.xstartaxisEditFieldLabel = uilabel(app.UIFigure);
            app.xstartaxisEditFieldLabel.Text = 'x start axis';
            app.xstartaxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.xendaxisEditFieldLabel = uilabel(app.UIFigure);
            app.xendaxisEditFieldLabel.Text = 'x end axis';
            app.xendaxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.ystartaxisEditFieldLabel = uilabel(app.UIFigure);
            app.ystartaxisEditFieldLabel.Text = 'y start axis';
            app.ystartaxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.yendaxisEditFieldLabel = uilabel(app.UIFigure);
            app.yendaxisEditFieldLabel.Text = 'y end axis';
            app.yendaxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.curveareaEditFieldLabel = uilabel(app.UIFigure);
            app.curveareaEditFieldLabel.Text = 'curve area';
            app.curveareaEditField = uieditfield(app.UIFigure, 'numeric');
            app.curveareaEditField.ValueChangedFcn = createCallbackFcn(app, @curveareaEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = car_move_A01383048

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end